classdef FinalPushinProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlayerWins1Label             matlab.ui.control.Label
        MinBetLabel                  matlab.ui.control.Label
        WaitingForOtherPlayersLabel  matlab.ui.control.Label
        PotLabel                     matlab.ui.control.Label
        QuitButton                   matlab.ui.control.Button
        BalanceLabel                 matlab.ui.control.Label
        Player3Button                matlab.ui.control.Button
        Player2Button                matlab.ui.control.Button
        Player1Button                matlab.ui.control.Button
        PLAYERSButton_2              matlab.ui.control.Button
        PLAYERSButton                matlab.ui.control.Button
        PLAYButton                   matlab.ui.control.Button
        shuffleGIF                   matlab.ui.control.Image
        Image7                       matlab.ui.control.Image
        player1_Card2                matlab.ui.control.Image
        player1_Card1                matlab.ui.control.Image
        Image6_4                     matlab.ui.control.Image
        Image6_3                     matlab.ui.control.Image
        Image6_2                     matlab.ui.control.Image
        Image6                       matlab.ui.control.Image
        communityCard5               matlab.ui.control.Image
        communityCard4               matlab.ui.control.Image
        communityCard3               matlab.ui.control.Image
        communityCard2               matlab.ui.control.Image
        communityCard1               matlab.ui.control.Image
        twentyChip                   matlab.ui.control.Image
        hundoChip                    matlab.ui.control.Image
        fityChip                     matlab.ui.control.Image
        BetButton                    matlab.ui.control.Button
        FoldButton                   matlab.ui.control.Button
        CheckButton                  matlab.ui.control.Button
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        card1v
        card1s
        card2v
        card2s
        card3v
        card3s
        card4v
        card4s
        card5v
        card5s
        playerNum
        player
        p1
        p2
        p3
        deck
        pot = 0;
        bet = 20;
        wDelay = 0.1;
        community
        channelID
        channelID2
        userAPIKey
%         Channel 1 is for community. After first turn, becomes game data
        readKey1
        writeKey1
%         Channel 2 is for pocket cards
        readKey2
        writeKey2
%         Channel 3 is for game data
        channelID3
        readKey3
        writeKey3
        qTimer = 0;
        roundsLeft = 3;
        card4Path
        card5Path
    end
    
    methods (Access = public)

%     At the game's startup, the fields are as follows:
%     1, 2, 3, 4, 5: filpath character vector for community cards
%     6, 7: filePath character vector for Player 1's pocket cards
%     8, 9: filePath for Player 2's pocket cards
%     10, 11: filePath for Player 3's pocket cards

%     During a round, the fields are as follows:
%     1: Player turn. This field decides who's playing, and who isn't.
%     2: Rounds left before another community card is revealed, or until round is over.
%     3: Money sitting in pot
%     3: Player 1 balance
%     4: Player 2 balance
%     5: Player 3 balance
        function winner = win(app)
            if app.playerNum ==2
                app.p1.Pocket.Cards
                app.community.Cards
                
                handp1 = Hand(app.p1.Pocket.Cards, app.community.Cards);
                handp2 = Hand(app.p2.Pocket.Cards, app.community.Cards);
                disp(handp1.Ranking)
                disp(handp2.Ranking)

                if handp1.Ranking > handp2.Ranking
                    
                    winner = 1;
                elseif handp1.Ranking < handp2.Ranking 
                    winner = 2;
                else
                    if handp1.isGreater(handp2)
                        winner = 1;
                    else
                        winner = 2;
                    end

                end
            else
                handp1 = Hand(app.p1.Pocket.Cards, app.community.Cards);
                handp2 = Hand(app.p2.Pocket.Cards, app.community.Cards);
                handp3 = Hand(app.p3.Pocket.Cards, app.community.Cards);

                if handp1.Ranking == handp2.Ranking
                    if handp1.isGreater(handp2)
                        winner = 1;
                    else
                        winner = 2;
                    end

                elseif handp1.Ranking == handp3.Ranking
                    if handp1.isGreater(handp3)
                        winner = 1;
                    else
                        winner = 3;
                    end

                elseif handp3.Ranking == handp3.Ranking
                    if handp3.isGreater(handp2)
                        winner = 3;
                    else
                        winner = 2;
                    end
                elseif handp1.Ranking > handp2.Ranking
                    winner = 1;
                    if handp1.Ranking > handp3.Ranking
                        winner = 1;
                    else
                        winner = 3;
                    end
                elseif handp1.Ranking < handp2.Ranking
                    winner = 2;
                    if handp2.Ranking > handp3.Ranking
                        winner = 2;
                    else
                        winner = 3;
                    end
                end
                
                
            end
            app.PotLabel.Visible = 'off';
            app.PotLabel.Text = strcat('Pot: ', num2str(app.pot));
            app.PotLabel.Visible = 'on';

             app.WaitingForOtherPlayersLabel.Visible = 'off';
             if winner == 1
                 app.PlayerWins1Label.Text = "Player 1 Wins!";
             elseif winner == 2
                 app.PlayerWins1Label.Text = "Player 2 Wins!";
             else
                 app.PlayerWins1Label.Text = "Player 3 Wins!";
             end
             app.PlayerWins1Label.Visible = 'on';

             values = {winner+10, app.roundsLeft, app.bet, app.pot, app.p1.Balance.Amount, app.p2.Balance.Amount, app.p3.Balance.Amount};
             pause(3);
             thingSpeakWrite(app.channelID3, 'WriteKey', app.writeKey3, 'Fields', [1, 2, 3, 4, 5, 6, 7], 'Values', values);

        end


        function output = endTurn(app)

            app.FoldButton.Enable = 'off';
            app.CheckButton.Enable = 'off';
            app.BetButton.Enable = 'off';
            app.FoldButton.Visible = 'off';
            app.CheckButton.Visible = 'off';
            app.BetButton.Visible = 'off';
            app.WaitingForOtherPlayersLabel.Visible = 'on';

            playerTurn = app.player + 1;

            app.roundsLeft = app.roundsLeft - 1;


            if app.playerNum == 3
                values = {playerTurn, app.roundsLeft, app.bet, app.pot, app.p1.Balance.Amount, app.p2.Balance.Amount, app.p3.Balance.Amount};
                pause(3);
                thingSpeakWrite(app.channelID3, 'WriteKey', app.writeKey3, 'Fields', [1, 2, 3, 4, 5, 6, 7], 'Values', values);
            else
                values = {playerTurn, app.roundsLeft, app.bet, app.pot, app.p1.Balance.Amount, app.p2.Balance.Amount};
                pause(3);
                thingSpeakWrite(app.channelID3, 'WriteKey', app.writeKey3, 'Fields', [1, 2, 3, 4, 5, 6], 'Values', values);
            end
            app.wait()

                %                 run some code and end game, maybe create endRound()?
        end

        function output = start(app)
            app.WaitingForOtherPlayersLabel.Visible = 'off';
            app.FoldButton.Enable = 'on';
            app.CheckButton.Enable = 'on';
            app.BetButton.Enable = 'on';
            app.FoldButton.Visible = 'on';
            app.CheckButton.Visible = 'on';
            app.BetButton.Visible = 'on';
            if app.playerNum == 3
                data = thingSpeakRead(app.channelID3, 'ReadKey', app.readKey3, 'Fields', [1, 2, 3, 4, 5, 6, 7], 'OutputFormat', 'Table');
                playerTurn = data{1,2};
                disp(playerTurn)
                if playerTurn == 4
                    app.win()
                end
            else
                data = thingSpeakRead(app.channelID3, 'ReadKey', app.readKey3, 'Fields', [1, 2, 3, 4, 5, 6], 'OutputFormat', 'Table');
                playerTurn = data{1,2};
                disp(playerTurn)
                if playerTurn == 3
                    app.bet = data{1, 4};
                    app.pot = data{1, 5};
                    app.win()
                end
            end
            disp(data);
            app.bet = data{1, 4};
            app.pot = data{1, 5};

        end

        function waiting = wait(app)

            app.FoldButton.Enable = 'off';
            app.CheckButton.Enable = 'off';
            app.BetButton.Enable = 'off';
            app.FoldButton.Visible = 'off';
            app.CheckButton.Visible = 'off';
            app.BetButton.Visible = 'off';
            
            if app.playerNum == 3
                data = thingSpeakRead(app.channelID3, 'ReadKey', app.readKey3, 'Fields', [1, 2, 3, 4, 5, 6], 'OutputFormat', 'Table');
            else
                data = thingSpeakRead(app.channelID3, 'ReadKey', app.readKey3, 'Fields', [1, 2, 3, 4, 5], 'OutputFormat', 'Table');
            end


            if ~isempty(data)
                playerTurn = data{1, 2};

                if playerTurn > 9
                    playerTurn = playerTurn -10;

                    app.WaitingForOtherPlayersLabel.Visible = 'off';
                    if winner == 1
                        app.PlayerWins1Label.Text = "Player 1 Wins!";
                    elseif winner == 2
                        app.PlayerWins1Label.Text = "Player 2 Wins!";
                    else
                        app.PlayerWins1Label.Text = "Player 3 Wins!";
                    end
                    app.PlayerWins1Label.Visible = 'on';
                    pause(60)


                end

                
                if playerTurn == app.player || playerTurn == 4
                    disp(playerTurn);
                    app.pot = data{1, 5};
                    app.bet = data{1, 4};

                    app.PotLabel.Text = strcat('Pot: ', num2str(app.pot));
                    app.MinBetLabel.Text = strcat('Min Bet: ', num2str(app.bet));
                    app.FoldButton.Enable = 'on';
                    app.CheckButton.Enable = 'on';
                    app.BetButton.Enable = 'on';
                    app.start();
                    
                else
                    pause(app.wDelay);
                    app.pot = data{1, 5};
                    app.bet = data{1, 4};

                    app.PotLabel.Text = strcat('Pot: ', num2str(app.pot));
                    app.MinBetLabel.Text = strcat('Min Bet: ', num2str(app.bet));
                    app.wait();
                end
            else
                pause(app.wDelay);
                app.wait();
            end
        end
        
        function results = waitInitial(app)
            data = thingSpeakRead(app.channelID, 'ReadKey', app.readKey1, 'Fields', [1, 2, 3, 4, 5], 'OutputFormat', 'Table');
            if isempty(data)
                pause(app.wDelay);
                app.waitInitial();
            else
                app.FoldButton.Enable = 'on';
                app.CheckButton.Enable = 'on';
                app.BetButton.Enable = 'on';
                app.card4Path = data{1, 5};
                app.card5Path = data{1, 6};
                app.communityCard1.Visible = 'off';
                app.communityCard1.ImageSource = string(data{1, 2});
                pause(0.1);
                app.communityCard1.Visible = 'on';
                pause(0.1);
                app.communityCard2.Visible = 'off';
                app.communityCard2.ImageSource = string(data{1, 3});
                pause(0.1);
                app.communityCard2.Visible = 'on';
                pause(0.1);
                app.communityCard3.Visible = 'off';
                app.communityCard3.ImageSource = string(data{1, 4});
                pause(0.1);
                app.communityCard3.Visible = 'on';
                pause(0.1);
                app.communityCard4.Visible = 'off';
                app.communityCard4.ImageSource = string(data{1, 5});
                pause(0.1);
                app.communityCard4.Visible = 'on';
                pause(0.1);
                app.communityCard5.Visible = 'off';
                app.communityCard5.ImageSource = string(data{1, 6});
                pause(0.1);
                app.communityCard5.Visible = 'on';
                pause(0.1);
                if app.playerNum == 3
                    data = thingSpeakRead(app.channelID2, 'ReadKey', app.readKey2, 'Fields', [1, 2, 3, 4, 5, 6], 'OutputFormat', 'Table');
                else
                    data = thingSpeakRead(app.channelID2, 'ReadKey', app.readKey2, 'Fields', [1, 2, 3, 4], 'OutputFormat', 'Table');
                end
                app.player1_Card1.Visible = 'off';
%                 ccfilname1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard1v,pcard1s,'s.png'));
                app.player1_Card1.ImageSource = string(data{1, app.player * 2});
                pause(0.1);
                app.player1_Card1.Visible = 'on';
                pause(0.1);

                app.player1_Card2.Visible = 'off';
%                 ccfilename1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard2v,pcard2s,'s.png'));
                app.player1_Card2.ImageSource = string(data{1, app.player * 2 + 1});
                pause(0.1);
                app.player1_Card2.Visible = 'on';
                pause(0.1);
                app.wait();
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BalanceLabel.Visible = 'off';
            app.PotLabel.Visible = 'off';
            app.MinBetLabel.Visible = 'off';
            app.PLAYButton.Visible = 'off';
            app.Player1Button.Visible = 'off';
            app.Player2Button.Visible = 'off';
            app.Player3Button.Visible = 'off';
            app.PlayerWins1Label.Visible = 'off';
            app.WaitingForOtherPlayersLabel.Visible = 'off';
            app.deck = Deck;

            app.wDelay = 3;
            app.channelID = 1672381;
            app.channelID2 = 1674047;
            app.channelID3 = 1674058;

            % This is available from https://thingspeak.com/account/profile
            app.userAPIKey = '1F2C9VHHVRKX7YKK';

            % Step 2
            % Insert your own API keys here
            app.readKey1 = 'KQ434P7543DP07MB';
            app.writeKey1 = '3RL9LNI9VDBKDJ6Y';
            app.readKey2 = 'BLYG7X46C4MTFKMS';
            app.writeKey2 = '75HSEKQDG7Z9PLP6';
            app.writeKey3 = 'DAE9C9XCXBBS6S8M';
            app.readKey3 = '7TQNZ8ZGRHFYWQO8';
            % This code removes old data from the ThingSpeak channel


        end

        % Button pushed function: FoldButton
        function FoldButtonPushed(app, event)
            app.FoldButton.Enable = 'off';
            app.CheckButton.Enable = 'off';
            app.BetButton.Enable = 'off';
            

            load laughter
            %load laughter
            sound (y, Fs)
            app.endTurn();
        end

        % Button pushed function: BetButton
        function BetButtonPushed(app, event)
            app.FoldButton.Visible = 'off';
            app.CheckButton.Visible = 'off';
            app.BetButton.Visible = 'off';
            app.BalanceLabel.Visible = 'off';
            app.PotLabel.Visible = 'off';
            app.MinBetLabel.Visible = 'off';

            app.twentyChip.Visible = 'on';
            app.fityChip.Visible = 'on';
            app.hundoChip.Visible = 'on';

            app.twentyChip.Enable = 'on';
            app.fityChip.Enable = 'on';
            app.hundoChip.Enable = 'on';
        end

        % Image clicked function: hundoChip
        function hundoChipImageClicked(app, event)
            app.FoldButton.Visible = 'on';
            app.CheckButton.Visible = 'on';
            app.BetButton.Visible = 'on';

            app.twentyChip.Visible = 'off';
            app.fityChip.Visible = 'off';
            app.hundoChip.Visible = 'off';

            app.twentyChip.Enable = 'off';
            app.fityChip.Enable = 'off';
            app.hundoChip.Enable = 'off';

            app.bet = app.bet + 100;

            if app.player == 1
                app.p1.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p1.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 2
                app.p2.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p2.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 3
                app.p3.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p3.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            end

            app.pot = app.pot + app.bet;
            app.PotLabel.Visible = 'off';
            app.MinBetLabel.Visible='off';
            app.PotLabel.Text = strcat('Pot: ', num2str(app.pot));
            app.MinBetLabel.Text = strcat('Min Bet: ', num2str(app.bet));
            app.PotLabel.Visible = 'on';
            app.MinBetLabel.Visible='on';
            app.endTurn();
        end

        % Image clicked function: fityChip
        function fityChipImageClicked(app, event)
            app.FoldButton.Visible = 'on';
            app.CheckButton.Visible = 'on';
            app.BetButton.Visible = 'on';

            app.twentyChip.Visible = 'off';
            app.fityChip.Visible = 'off';
            app.hundoChip.Visible = 'off';

            app.twentyChip.Enable = 'off';
            app.fityChip.Enable = 'off';
            app.hundoChip.Enable = 'off';

            app.bet = app.bet + 50;

            if app.player == 1
                app.p1.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p1.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 2
                app.p2.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p2.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 3
                app.p3.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p3.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            end

            app.pot = app.pot + app.bet;
            app.PotLabel.Visible = 'off';
            app.MinBetLabel.Visible='off';
            app.PotLabel.Text = strcat('Pot: ', num2str(app.pot));
            app.MinBetLabel.Text = strcat('Min Bet: ', num2str(app.bet));
            app.PotLabel.Visible = 'on';
            app.MinBetLabel.Visible = 'on';
            app.endTurn();
        end

        % Image clicked function: twentyChip
        function twentyChipImageClicked(app, event)
            app.FoldButton.Visible = 'on';
            app.CheckButton.Visible = 'on';
            app.BetButton.Visible = 'on';

            app.twentyChip.Visible = 'off';
            app.fityChip.Visible = 'off';
            app.hundoChip.Visible = 'off';

            app.twentyChip.Enable = 'off';
            app.fityChip.Enable = 'off';
            app.hundoChip.Enable = 'off';

            app.bet = app.bet + 20;


            if app.player == 1
                app.p1.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p1.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 2
                app.p2.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p2.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 3
                app.p3.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p3.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            end

            app.pot = app.pot + app.bet;
            app.PotLabel.Visible = 'off';
            app.MinBetLabel.Visible='off';
            app.PotLabel.Text = strcat('Pot: ', num2str(app.pot));
            app.MinBetLabel.Text = strcat('Min Bet: ', num2str(app.bet));
            app.PotLabel.Visible = 'on';
            app.MinBetLabel.Visible = 'on';
            app.endTurn();

        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)


            app.PLAYButton.Visible = 'off';
           
            
            for i = 10 % loop through the struct
                app.shuffleGIF.Visible = 'on';%  shows image
                app.shuffleGIF.Enable = 'on';
                pause(2); % waits for 10 secs
                app.shuffleGIF.Visible = 'off';
                app.shuffleGIF.Enable = 'off';
            end
            
            app.Image7.Visible = 'off';
            app.FoldButton.Visible = 'off';
            app.CheckButton.Visible = 'off';
            app.BetButton.Visible = 'off';
            app.Image.Visible = 'on';
            pause(0.1);
            app.Image6.Visible = 'on';
            pause(0.1);
            app.Image6_2.Visible = 'on';
            pause(0.1);
            app.communityCard1.Visible = 'on';
            pause(0.1);
            app.communityCard2.Visible = 'on';
            pause(0.1);
            app.communityCard3.Visible = 'on';
            pause(0.1);
            app.communityCard4.Visible = 'on';
            pause(0.1);
            app.communityCard5.Visible = 'on';
            pause(0.1);
            app.Image6_4.Visible = 'on';
            pause(0.1);
            app.Image6_3.Visible = 'on';
            pause(0.1);
            app.player1_Card2.Visible = 'on';
            pause(0.1);
            app.player1_Card1.Visible = 'on';
            pause(0.5);


            %app.FoldButton.Visible = 'on';
            %app.CheckButton.Visible = 'on';
            %app.BetButton.Visible = 'on';

            %app.BetButton.Enable = 'on';
            %app.FoldButton.Enable = 'on';
            %app.CheckButton.Enable = 'on';
            app.PLAYButton.Enable = 'off';
            app.BalanceLabel.Visible = 'on';
            app.PotLabel.Visible = 'on';
            app.MinBetLabel.Visible = 'on';

            if app.player == 1

                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p1.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 2

                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p2.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 3

                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p3.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            end
            app.BalanceLabel.Visible = 'on';
            app.PotLabel.Visible = 'on';
            app.MinBetLabel.Visible = 'on';

            if app.player == 1
                url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(app.channelID), app.userAPIKey);
                webwrite(url, weboptions('RequestMethod','delete'));
                url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(app.channelID2), app.userAPIKey);
                webwrite(url, weboptions('RequestMethod','delete'));
                url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(app.channelID3), app.userAPIKey);
                webwrite(url, weboptions('RequestMethod','delete'));
                app.community = Community;
                for i = 1:5
                    app.community.addCard(app.deck.drawCard);
                end
                [app.card1v, app.card2v, app.card3v, app.card4v, app.card5v] = app.community.Cards.Value;
                [app.card1s, app.card2s, app.card3s, app.card4s, app.card5s] = app.community.Cards.Suit;


                if app.playerNum == 3
                    [pcard1v, pcard2v] = app.p1.Pocket.Cards.Value;
                    [pcard1s, pcard2s] = app.p1.Pocket.Cards.Suit;
                    ccfilenamep1_c1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard1v,pcard1s,'s.png'));
                    ccfilenamep1_c2 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard2v,pcard2s,'s.png'));
                    [pcard1v, pcard2v] = app.p2.Pocket.Cards.Value;
                    [pcard1s, pcard2s] = app.p2.Pocket.Cards.Suit;
                    ccfilenamep2_c1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard1v,pcard1s,'s.png'));
                    ccfilenamep2_c2 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard2v,pcard2s,'s.png'));
                    [pcard1v, pcard2v] = app.p3.Pocket.Cards.Value;
                    [pcard1s, pcard2s] = app.p3.Pocket.Cards.Suit;
                    ccfilenamep3_c1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard1v,pcard1s,'s.png'));
                    ccfilenamep3_c2 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard2v,pcard2s,'s.png'));
                    pause(3);
                    thingSpeakWrite(app.channelID2, 'WriteKey', app.writeKey2, 'Fields', [1, 2, 3, 4, 5, 6], 'Values', {ccfilenamep1_c1, ccfilenamep1_c2, ccfilenamep2_c1, ccfilenamep2_c2, ccfilenamep3_c1, ccfilenamep3_c2});
                end
                if app.playerNum == 2
                    [pcard1v, pcard2v] = app.p1.Pocket.Cards.Value;
                    [pcard1s, pcard2s] = app.p1.Pocket.Cards.Suit;
                    ccfilenamep1_c1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard1v,pcard1s,'s.png'));
                    ccfilenamep1_c2 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard2v,pcard2s,'s.png'));
                    [pcard1v, pcard2v] = app.p2.Pocket.Cards.Value;
                    [pcard1s, pcard2s] = app.p2.Pocket.Cards.Suit;
                    ccfilenamep2_c1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard1v,pcard1s,'s.png'));
                    ccfilenamep2_c2 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard2v,pcard2s,'s.png'));
                    pause(3);
                    thingSpeakWrite(app.channelID2, 'WriteKey', app.writeKey2, 'Fields', [1, 2, 3, 4], 'Values', {ccfilenamep1_c1, ccfilenamep1_c2, ccfilenamep2_c1, ccfilenamep2_c2});
                end
                
%                 if app.playerNum == 2
%                     app.p1 = Play(app.deck, 300);
%                     app.p2 = Play(app.deck, 300);
%                 else 
%                     app.p1 = Play(app.deck, 300);
%                     app.p2 = Play(app.deck, 300);
%                     app.p3 = Play(app.deck, 300);
%                 end
                app.communityCard1.Visible = 'off';
                ccfilename1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',app.card1v,app.card1s,'s.png'));
                app.communityCard1.ImageSource = ccfilename1;
                pause(0.1);
                app.communityCard1.Visible = 'on';
                pause(0.1);

                app.communityCard2.Visible = 'off';
                ccfilename2 = convertStringsToChars(strcat('./FinalPro/CardDeck/',app.card2v,app.card2s,'s.png'));
                app.communityCard2.ImageSource = ccfilename2;
                pause(0.1);
                app.communityCard2.Visible = 'on';
                pause(0.1);

                app.communityCard3.Visible = 'off';
                ccfilename3 = convertStringsToChars(strcat('./FinalPro/CardDeck/',app.card3v,app.card3s,'s.png'));
                app.communityCard3.ImageSource = ccfilename3;
                pause(0.1);
                app.communityCard3.Visible = 'on';
                pause(0.1);

                app.communityCard4.Visible = 'off';
                ccfilename4 = convertStringsToChars(strcat('./FinalPro/CardDeck/',app.card4v,app.card4s,'s.png'));
                app.communityCard4.ImageSource = ccfilename4;
                pause(0.1);
                app.communityCard4.Visible = 'on';

                app.communityCard5.Visible = 'off';
                ccfilename5 = convertStringsToChars(strcat('./FinalPro/CardDeck/',app.card5v,app.card5s,'s.png'));
                app.communityCard5.ImageSource = ccfilename5;
                pause(0.1);
                app.communityCard5.Visible = 'on';

                [pcard1v, pcard2v] = app.p1.Pocket.Cards.Value;
                [pcard1s, pcard2s] = app.p1.Pocket.Cards.Suit;

                thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey1, 'Fields', [1, 2, 3, 4, 5], 'Values', {ccfilename1, ccfilename2, ccfilename3, ccfilename4, ccfilename5});

                app.player1_Card1.Visible = 'off';
                ccfilename1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard1v,pcard1s,'s.png'));
                app.player1_Card1.ImageSource = ccfilename1;
                pause(0.1);
                app.player1_Card1.Visible = 'on';
                pause(0.1);

                app.player1_Card2.Visible = 'off';
                ccfilename1 = convertStringsToChars(strcat('./FinalPro/CardDeck/',pcard2v,pcard2s,'s.png'));
                app.player1_Card2.ImageSource = ccfilename1;
                pause(0.1);
                app.player1_Card2.Visible = 'on';
                pause(0.1);

                app.FoldButton.Enable = 'on';
                app.CheckButton.Enable = 'on';
                app.BetButton.Enable = 'on';

                app.FoldButton.Visible = 'on';
                app.CheckButton.Visible = 'on';
                app.BetButton.Visible = 'on';
                pause(3);


            else
                app.WaitingForOtherPlayersLabel.Visible = 'on';
                app.waitInitial()
            end

            
        end

        % Button pushed function: PLAYERSButton
        function PLAYERSButtonPushed(app, event)
         
            app.PLAYERSButton.Visible = 'off';
            app.PLAYERSButton_2.Visible = 'off';
            app.Player1Button.Visible = 'on';
            app.Player2Button.Visible = 'on';
            app.playerNum = 2;
            app.p1 = Play(app.deck, 300);
            app.p2 = Play(app.deck, 300);
            
            %app.PLAYButton.Visible = 'on';

           
        end

        % Button pushed function: PLAYERSButton_2
        function PLAYERSButton_2Pushed(app, event)
                     
            app.PLAYERSButton.Visible = 'off';
            app.PLAYERSButton_2.Visible = 'off';
            app.Player1Button.Visible = 'on';
            app.Player2Button.Visible = 'on';
            app.Player3Button.Visible = 'on';
            app.playerNum = 3;
            app.p1 = Play(app.deck, 300);
            app.p2 = Play(app.deck, 300);
            app.p3 = Play(app.deck, 300);

        end

        % Button pushed function: Player1Button
        function Player1ButtonPushed(app, event)
            app.PLAYButton.Visible = 'on';
            app.player = 1;
            app.Player1Button.Visible = 'off';
            app.Player2Button.Visible = 'off';
            app.Player3Button.Visible = 'off';
        end

        % Button pushed function: Player2Button
        function Player2ButtonPushed(app, event)
            app.PLAYButton.Visible = 'on';
            app.player = 2;
            app.Player1Button.Visible = 'off';
            app.Player2Button.Visible = 'off';
            app.Player3Button.Visible = 'off';

        end

        % Button pushed function: Player3Button
        function Player3ButtonPushed(app, event)
            app.PLAYButton.Visible = 'on';
            app.player = 3;
            app.Player1Button.Visible = 'off';
            app.Player2Button.Visible = 'off';
            app.Player3Button.Visible = 'off';
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            if app.player == 1
                app.p1.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p1.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 2
                app.p2.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p2.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            elseif app.player == 3
                app.p3.raise(app.bet)
                app.BalanceLabel.Text = strcat('Balance: ', num2str(app.p3.Balance.Amount));
                app.BalanceLabel.Visible = 'on';
            end

            app.pot = app.pot + app.bet;
            app.PotLabel.Visible = 'off';
            app.MinBetLabel.Visible='off';
            app.MinBetLabel.Text = strcat('Min Bet: ', num2str(app.bet));
            app.PotLabel.Text = strcat('Pot: ', num2str(app.pot));
            app.PotLabel.Visible = 'on';
            app.MinBetLabel.Visible = 'on';
            app.endTurn();
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'PokerTable.png';
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Visible = 'off';
            app.CheckButton.Text = 'Check';
            app.FoldButton = uibutton(app.UIFigure, 'push');
            app.FoldButton.ButtonPushedFcn = createCallbackFcn(app, @FoldButtonPushed, true);
            app.FoldButton.Enable = 'off';
            app.FoldButton.Visible = 'off';
            app.FoldButton.Text = 'Fold';
            app.BetButton = uibutton(app.UIFigure, 'push');
            app.BetButton.ButtonPushedFcn = createCallbackFcn(app, @BetButtonPushed, true);
            app.BetButton.Enable = 'off';
            app.BetButton.Visible = 'off';
            app.BetButton.Text = 'Bet';
            app.fityChip = uiimage(app.UIFigure);
            app.fityChip.ImageClickedFcn = createCallbackFcn(app, @fityChipImageClicked, true);
            app.fityChip.Enable = 'off';
            app.fityChip.Visible = 'off';
            app.fityChip.ImageSource = 'fityChip.PNG';
            app.hundoChip = uiimage(app.UIFigure);
            app.hundoChip.ImageClickedFcn = createCallbackFcn(app, @hundoChipImageClicked, true);
            app.hundoChip.Enable = 'off';
            app.hundoChip.Visible = 'off';
            app.hundoChip.ImageSource = 'hundoChip.png';
            app.twentyChip = uiimage(app.UIFigure);
            app.twentyChip.ImageClickedFcn = createCallbackFcn(app, @twentyChipImageClicked, true);
            app.twentyChip.Enable = 'off';
            app.twentyChip.Visible = 'off';
            app.twentyChip.ImageSource = 'twentyChip.png';
            app.communityCard1 = uiimage(app.UIFigure);
            app.communityCard1.Visible = 'off';
            app.communityCard1.ImageSource = 'FaceDownCard.jpeg';
            app.communityCard2 = uiimage(app.UIFigure);
            app.communityCard2.Visible = 'off';
            app.communityCard2.ImageSource = 'FaceDownCard.jpeg';
            app.communityCard3 = uiimage(app.UIFigure);
            app.communityCard3.Visible = 'off';
            app.communityCard3.ImageSource = 'FaceDownCard.jpeg';
            app.communityCard4 = uiimage(app.UIFigure);
            app.communityCard4.Visible = 'off';
            app.communityCard4.ImageSource = 'FaceDownCard.jpeg';
            app.communityCard5 = uiimage(app.UIFigure);
            app.communityCard5.Visible = 'off';
            app.communityCard5.ImageSource = 'FaceDownCard.jpeg';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = 'FaceDownCard.jpeg';
            app.Image6_2 = uiimage(app.UIFigure);
            app.Image6_2.Visible = 'off';
            app.Image6_2.ImageSource = 'FaceDownCard.jpeg';
            app.Image6_3 = uiimage(app.UIFigure);
            app.Image6_3.Visible = 'off';
            app.Image6_3.ImageSource = 'FaceDownCard.jpeg';
            app.Image6_4 = uiimage(app.UIFigure);
            app.Image6_4.Visible = 'off';
            app.Image6_4.ImageSource = 'FaceDownCard.jpeg';
            app.player1_Card1 = uiimage(app.UIFigure);
            app.player1_Card1.Visible = 'off';
            app.player1_Card1.ImageSource = 'FaceDownCard.jpeg';
            app.player1_Card2 = uiimage(app.UIFigure);
            app.player1_Card2.Visible = 'off';
            app.player1_Card2.ImageSource = 'FaceDownCard.jpeg';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = 'TitlePage.png';
            app.shuffleGIF = uiimage(app.UIFigure);
            app.shuffleGIF.Enable = 'off';
            app.shuffleGIF.Visible = 'off';
            app.shuffleGIF.ImageSource = 'deckShuffle.gif';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.PLAYERSButton = uibutton(app.UIFigure, 'push');
            app.PLAYERSButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYERSButtonPushed, true);
            app.PLAYERSButton.Text = '2 PLAYERS';
            app.PLAYERSButton_2 = uibutton(app.UIFigure, 'push');
            app.PLAYERSButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLAYERSButton_2Pushed, true);
            app.PLAYERSButton_2.Text = '3 PLAYERS';
            app.Player1Button = uibutton(app.UIFigure, 'push');
            app.Player1Button.ButtonPushedFcn = createCallbackFcn(app, @Player1ButtonPushed, true);
            app.Player1Button.Text = 'Player 1';
            app.Player2Button = uibutton(app.UIFigure, 'push');
            app.Player2Button.ButtonPushedFcn = createCallbackFcn(app, @Player2ButtonPushed, true);
            app.Player2Button.Text = 'Player 2';
            app.Player3Button = uibutton(app.UIFigure, 'push');
            app.Player3Button.ButtonPushedFcn = createCallbackFcn(app, @Player3ButtonPushed, true);
            app.Player3Button.Text = 'Player 3';
            app.BalanceLabel = uilabel(app.UIFigure);
            app.BalanceLabel.Text = 'Balance: ';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.PotLabel = uilabel(app.UIFigure);
            app.PotLabel.Text = 'Pot: ';
            app.WaitingForOtherPlayersLabel = uilabel(app.UIFigure);
            app.WaitingForOtherPlayersLabel.Text = 'Waiting For Other Players...';
            app.MinBetLabel = uilabel(app.UIFigure);
            app.MinBetLabel.Text = 'Min Bet: ';
            app.PlayerWins1Label = uilabel(app.UIFigure);
            app.PlayerWins1Label.Text = 'Player Wins 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalPushinProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end